classdef testagoravai < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        Panel_2                       matlab.ui.container.Panel
        Image                         matlab.ui.control.Image
        Label                         matlab.ui.control.Label
        Panel                         matlab.ui.container.Panel
        Label_2                       matlab.ui.control.Label
        InstruesdeusoLabel            matlab.ui.control.Label
        RightPanel                    matlab.ui.container.Panel
        BodeFinalFaseAxes             matlab.ui.control.UIAxes
        BodeFinalMagAxes              matlab.ui.control.UIAxes
        TempoAxes                     matlab.ui.control.UIAxes
        BodeFaseAxes                  matlab.ui.control.UIAxes
        BodeMagAxes                   matlab.ui.control.UIAxes
        LeftPanel                     matlab.ui.container.Panel
        ConfiguraesLabel              matlab.ui.control.Label
        ResultadoTextArea             matlab.ui.control.TextArea
        ResultadoTextAreaLabel        matlab.ui.control.Label
        ProjetarButton                matlab.ui.control.Button
        TipoDropDown                  matlab.ui.control.DropDown
        IntegradoresnaplantaLabel     matlab.ui.control.Label
        LentoDropDown                 matlab.ui.control.DropDown
        AceitarespostamaislentaLabel  matlab.ui.control.Label
        EntradaDropDown               matlab.ui.control.DropDown
        EntradaDropDownLabel          matlab.ui.control.Label
        MFEditField                   matlab.ui.control.NumericEditField
        MargemdeFasedesejadaLabel     matlab.ui.control.Label
        ErroEditField                 matlab.ui.control.NumericEditField
        ErrodesejadoLabel             matlab.ui.control.Label
        PlantaPsEditField             matlab.ui.control.EditField
        PlantaPsEditFieldLabel        matlab.ui.control.Label
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: ProjetarButton
        function ProjetarButtonPushed(app, event)
            addpath('src')

            g = app.PlantaPsEditField.Value;
            e = app.ErroEditField.Value;
            mf = app.MFEditField.Value;
            ent = app.EntradaDropDown.Value;
            lento = app.LentoDropDown.Value;
            tipo = app.TipoDropDown.Value;

            try
                r = proj(g, e, mf, ent, lento, tipo);
                plota(r, app.BodeMagAxes, app.BodeFaseAxes, app.TempoAxes, ...
                app.BodeFinalMagAxes, app.BodeFinalFaseAxes)
                app.ResultadoTextArea.Value = splitlines(r.txt);
            catch erro
                uialert(app.UIFigure, erro.message, 'Erro')
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.LeftPanel = uipanel(app.UIFigure);
            app.PlantaPsEditFieldLabel = uilabel(app.LeftPanel);
            app.PlantaPsEditFieldLabel.Text = 'Planta P(s)';
            app.PlantaPsEditField = uieditfield(app.LeftPanel, 'text');
            app.PlantaPsEditField.Value = '10/(s*(s+1))';
            app.ErrodesejadoLabel = uilabel(app.LeftPanel);
            app.ErrodesejadoLabel.Text = 'Erro desejado';
            app.ErroEditField = uieditfield(app.LeftPanel, 'numeric');
            app.ErroEditField.Value = 0.05;
            app.MargemdeFasedesejadaLabel = uilabel(app.LeftPanel);
            app.MargemdeFasedesejadaLabel.Text = 'Margem de Fase desejada (graus)';
            app.MFEditField = uieditfield(app.LeftPanel, 'numeric');
            app.MFEditField.Value = 45;
            app.EntradaDropDownLabel = uilabel(app.LeftPanel);
            app.EntradaDropDownLabel.Text = 'Tipo de entrada';
            app.EntradaDropDown = uidropdown(app.LeftPanel);
            app.EntradaDropDown.Items = {'rampa', 'degrau'};
            app.EntradaDropDown.Value = 'rampa';
            app.AceitarespostamaislentaLabel = uilabel(app.LeftPanel);
            app.AceitarespostamaislentaLabel.Text = 'Aceita resposta mais lenta?';
            app.LentoDropDown = uidropdown(app.LeftPanel);
            app.LentoDropDown.Items = {'sim', 'nao'};
            app.LentoDropDown.Value = 'sim';
            app.IntegradoresnaplantaLabel = uilabel(app.LeftPanel);
            app.IntegradoresnaplantaLabel.Text = 'Integradores na planta';
            app.TipoDropDown = uidropdown(app.LeftPanel);
            app.TipoDropDown.Items = {'0', '1', '2'};
            app.TipoDropDown.Value = '1';
            app.ProjetarButton = uibutton(app.LeftPanel, 'push');
            app.ProjetarButton.ButtonPushedFcn = createCallbackFcn(app, @ProjetarButtonPushed, true);
            app.ProjetarButton.Text = 'Projetar compensador';
            app.ResultadoTextAreaLabel = uilabel(app.LeftPanel);
            app.ResultadoTextAreaLabel.Text = 'Resultado';
            app.ResultadoTextArea = uitextarea(app.LeftPanel);
            app.ConfiguraesLabel = uilabel(app.LeftPanel);
            app.ConfiguraesLabel.Text = 'Configurações';
            app.RightPanel = uipanel(app.UIFigure);
            app.BodeMagAxes = uiaxes(app.RightPanel);
            title(app.BodeMagAxes, 'Title')
            xlabel(app.BodeMagAxes, 'X')
            ylabel(app.BodeMagAxes, 'Y')
            zlabel(app.BodeMagAxes, 'Z')
            app.BodeFaseAxes = uiaxes(app.RightPanel);
            title(app.BodeFaseAxes, 'Title')
            xlabel(app.BodeFaseAxes, 'X')
            ylabel(app.BodeFaseAxes, 'Y')
            zlabel(app.BodeFaseAxes, 'Z')
            app.TempoAxes = uiaxes(app.RightPanel);
            title(app.TempoAxes, 'Title')
            xlabel(app.TempoAxes, 'X')
            ylabel(app.TempoAxes, 'Y')
            zlabel(app.TempoAxes, 'Z')
            app.BodeFinalMagAxes = uiaxes(app.RightPanel);
            title(app.BodeFinalMagAxes, 'Title')
            xlabel(app.BodeFinalMagAxes, 'X')
            ylabel(app.BodeFinalMagAxes, 'Y')
            zlabel(app.BodeFinalMagAxes, 'Z')
            app.BodeFinalFaseAxes = uiaxes(app.RightPanel);
            title(app.BodeFinalFaseAxes, 'Title')
            xlabel(app.BodeFinalFaseAxes, 'X')
            ylabel(app.BodeFinalFaseAxes, 'Y')
            zlabel(app.BodeFinalFaseAxes, 'Z')
            app.Panel = uipanel(app.UIFigure);
            app.InstruesdeusoLabel = uilabel(app.Panel);
            app.InstruesdeusoLabel.Text = 'Instruções de uso';
            app.Label_2 = uilabel(app.Panel);
            app.Label_2.Text = {'1. Digite a planta em funcao de s. Exemplo: 10/(s*(s+1))'; ''; '2. Informe o erro estacionario desejado em formato decimal'; ''; '3. Informe a margem de fase desejada em graus'; ''; '4. Eescolha a entrada usada no erro: degrau ou rampa'; ''; '5. Responda se seu sistema aceitaria uma resposta com delay:'; '   sim  -> usa LAG'; '   nao  -> usa LEAD'; ''; '6. Coloque quantos integradores a planta tem: 0, 1 ou 2'; ''; '7. Cliqueem Projetar compensador'};
            app.Panel_2 = uipanel(app.UIFigure);
            app.Label = uilabel(app.Panel_2);
            app.Label.Text = 'PUCSP - Laboratório aberto virtual - Projeção de compensadores LAG e LEAD';
            app.Image = uiimage(app.Panel_2);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'brasao-PUCSP-assinatura-principal-RGB.png');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = testagoravai

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end